classdef HDSP_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StartButton          matlab.ui.control.Button
        ThresholdEditField   matlab.ui.control.NumericEditField
        Label                matlab.ui.control.Label
        TimeEditField        matlab.ui.control.NumericEditField
        sEditFieldLabel      matlab.ui.control.Label
        LimitEditField       matlab.ui.control.NumericEditField
        MpaEditFieldLabel    matlab.ui.control.Label
        PressureEditField    matlab.ui.control.NumericEditField
        MPaEditFieldLabel    matlab.ui.control.Label
        f0EditField          matlab.ui.control.NumericEditField
        f0MHZEditFieldLabel  matlab.ui.control.Label
        NxEditField          matlab.ui.control.NumericEditField
        NxEditFieldLabel     matlab.ui.control.Label
        UIAxes_Result        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % 1. 禁用按钮，防止连点崩溃，并提示用户
app.StartButton.Enable = 'off';
app.StartButton.Text = '仿真运行中 (请等待 1~15 分钟)...';
drawnow; % 强制刷新 UI 界面

try
    % 2. 收集界面参数，打包成 params 结构体
    params.Nx = app.NxEditField.Value;
    params.f0 = app.f0EditField.Value;
    params.pressure = app.PressureEditField.Value;
    params.limit = app.LimitEditField.Value;
    params.time = app.TimeEditField.Value;
    params.threshold = app.ThresholdEditField.Value;
    
    % 3. 🚀 召唤你写的物理引擎核心！
    results = HDSP_Engine(params);
    
    % 4. 提取数据并绘制到 App 界面的 UIAxes 上
    % 重点：所有画图函数前面必须指定 app.UIAxes_Result
    imagesc(app.UIAxes_Result, results.x, results.y, results.cured_mask_2d);
    axis(app.UIAxes_Result, 'image');
    colormap(app.UIAxes_Result, [0.05 0.05 0.2; 0.9 0.9 0.1]); % 深蓝底，亮黄A
    
    % 更新标题，显示跑出来的 IoU
    title(app.UIAxes_Result, sprintf('固化形貌 (IoU: %.4f, 覆盖率: %.1f%%)', results.IoU, results.cured_coverage));
    
    % 5. 恢复按钮状态
    app.StartButton.Text = '✅ 仿真完成 (点击重跑)';
    app.StartButton.Enable = 'on';
    
catch ME
    % 如果出错，恢复按钮并报错
    app.StartButton.Text = '❌ 报错了';
    app.StartButton.Enable = 'on';
    uialert(app.UIFigure, ME.message, '仿真发生严重物理事故');
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Result = uiaxes(app.UIFigure);
            title(app.UIAxes_Result, '最终热固化预测')
            xlabel(app.UIAxes_Result, 'mm')
            ylabel(app.UIAxes_Result, 'mm')
            zlabel(app.UIAxes_Result, 'Z')
            app.NxEditFieldLabel = uilabel(app.UIFigure);
            app.NxEditFieldLabel.Text = '网格分辨率Nx';
            app.NxEditField = uieditfield(app.UIFigure, 'numeric');
            app.NxEditField.Value = 384;
            app.f0MHZEditFieldLabel = uilabel(app.UIFigure);
            app.f0MHZEditFieldLabel.Text = '频率f0(MHZ)';
            app.f0EditField = uieditfield(app.UIFigure, 'numeric');
            app.f0EditField.Value = 4;
            app.MPaEditFieldLabel = uilabel(app.UIFigure);
            app.MPaEditFieldLabel.Text = '目标声压（MPa）';
            app.PressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.PressureEditField.Value = 2;
            app.MpaEditFieldLabel = uilabel(app.UIFigure);
            app.MpaEditFieldLabel.Text = '截断上限（Mpa）';
            app.LimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.LimitEditField.Value = 2;
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = '照射时间（s）';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.Value = 0.28;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '固化阈值（℃）';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.Value = 65;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '启动仿真';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HDSP_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end